classdef VCGapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        DTab_2                     matlab.ui.container.Tab
        DplaneLabel                matlab.ui.control.Label
        threedimUIAxes             matlab.ui.control.UIAxes
        DTab                       matlab.ui.container.Tab
        DplanesLabel               matlab.ui.control.Label
        frontal                    matlab.ui.control.UIAxes
        sagital                    matlab.ui.control.UIAxes
        transversal                matlab.ui.control.UIAxes
        Lamp                       matlab.ui.control.Lamp
        DataloadedLabel            matlab.ui.control.Label
        StatsTab                   matlab.ui.container.Tab
        GridLayout2                matlab.ui.container.GridLayout
        ECGleadsPanel              matlab.ui.container.Panel
        GridLayout                 matlab.ui.container.GridLayout
        statsUIAxes1               matlab.ui.control.UIAxes
        statsUIAxes2               matlab.ui.control.UIAxes
        statsUIAxes3               matlab.ui.control.UIAxes
        statsUIAxes4               matlab.ui.control.UIAxes
        statsUIAxes5               matlab.ui.control.UIAxes
        statsUIAxes6               matlab.ui.control.UIAxes
        statsCheckBoxBiphasic      matlab.ui.control.CheckBox
        BiphasicPwaveLabel         matlab.ui.control.Label
        InleadsLabel               matlab.ui.control.Label
        leadsLabel                 matlab.ui.control.Label
        statsCheckBoxPartialIAB    matlab.ui.control.CheckBox
        PartialIABLabel            matlab.ui.control.Label
        Pseudo_UIAxes              matlab.ui.control.UIAxes
        OriginalsLabel             matlab.ui.control.Label
        statsCheckBoxBiphasic_2    matlab.ui.control.CheckBox
        BiphasicPwaveLabel_2       matlab.ui.control.Label
        InleadsLabel_2             matlab.ui.control.Label
        leadsLabel_2               matlab.ui.control.Label
        statsCheckBoxPartialIAB_2  matlab.ui.control.CheckBox
        PartialIABLabel_2          matlab.ui.control.Label
        PseudoLabel                matlab.ui.control.Label
        TotalcountLabel            matlab.ui.control.Label
        countOriginalLabel         matlab.ui.control.Label
        countPseudoLabel_2         matlab.ui.control.Label
        TotalcountLabel_2          matlab.ui.control.Label
        CurrentplotSpinnerLabel    matlab.ui.control.Label
        CurrentplotSpinner         matlab.ui.control.Spinner
        ViewDropDownLabel          matlab.ui.control.Label
        ViewDropDown               matlab.ui.control.DropDown
        LinetypeDropDownLabel      matlab.ui.control.Label
        LinetypeDropDown           matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        % public properties
        XML;
        xmlfiles;
        linetype=["ro","r.","r"];
        paxis=[-250 250 -250 250 -250 250];
        qrsaxis=[-1000 1100 -1000 1100 -1000 1100];
        allaxis=[-1000 1100 -1000 1100 -1000 1100];
        view; %The 3D-axis view
        VCG; %Transformed ECG to VCG
        str = 1100;
        pseudoLeads; %Length of the projection of the p-loop onto the pseudoleads.
        pseudoElectrodes; %Pseudo electrodes for the p-loop
        numerationOfElectrodes; %The order of the pseudoelectrodes placement
        %initial values
        fS = 500;
        On=1;
        Off=600;
        P_Onset;
        P_Offset;
        ltindex=1;
        axistype=[-1000 1100 -1000 1100 -1000 1100];
    end
    
    
    methods (Access = public)
        function calcVCG(app)
            
            app.XML=XMLECGParser("Testdata\" + convertCharsToStrings(app.xmlfiles(app.CurrentplotSpinner.Value).name));
            
            app.P_Onset=app.XML.TestInfo.POnset; %Set p-wave onset
            app.P_Offset=app.XML.TestInfo.POffset; %Set p-wave offset
            
            ecg = app.XML.MedianECG.ecg8;
            
            % Invers Dower with leads I,II,V1-V6
            % T=[0.1560   -0.0090   -0.1720   -0.0730    0.1220    0.2310    0.2390    0.1930;
            %    -0.2270    0.8860    0.0570   -0.0190   -0.1060   -0.0220    0.0400    0.0480;
            %      0.0210    0.1020   -0.2280   -0.3100   -0.2450   -0.0630    0.0540    0.1080]';  

            %Kors regression
            T=[0.3800   -0.0700   -0.1300    0.0500   -0.0100    0.1400    0.0600    0.5400;
             -0.0700    0.9300    0.0600   -0.0200   -0.0500    0.0600   -0.1700    0.1300;
            0.1100   -0.2300   -0.4300   -0.0600   -0.1400   -0.2000   -0.1100    0.3100]';
            
            % Transform to VCG based on matrix T
            app.VCG = ecg*T;
        end
        
        function plot2d(app)
            plot(app.frontal,app.VCG(app.On:app.Off,1),app.VCG(app.On:app.Off,3),app.linetype(app.ltindex)) %XY
            plot(app.sagital,app.VCG(app.On:app.Off,3),-app.VCG(app.On:app.Off,2),app.linetype(app.ltindex)) %YZ
            plot(app.transversal,app.VCG(app.On:app.Off,1),-app.VCG(app.On:app.Off,2),app.linetype(app.ltindex)) %XZ
            
            axis(app.frontal,app.axistype);
            axis(app.sagital,app.axistype);
            axis(app.transversal,app.axistype);
        end
        
        function plot3d(app)
            
            
            plot3(app.threedimUIAxes,[-app.str app.str],[0 0],[0 0],'k',[0 0],[-app.str app.str],[0 0],'k',[0 0],[0 0],[-app.str app.str],'k'); % plotter et kryds i 3D plottet
            text(app.threedimUIAxes,app.str,0,0,'Left');
            text(app.threedimUIAxes,-app.str,0,0,'Right');
            text(app.threedimUIAxes,0,app.str,0,'Posterior');
            text(app.threedimUIAxes,0,-app.str,0,'Anterior');
            text(app.threedimUIAxes,0,0,app.str,'Superior');
            text(app.threedimUIAxes,0,0,-app.str,'Inferior');
            
            hold(app.threedimUIAxes,'on');
            
            plot3(app.threedimUIAxes,app.VCG(app.On:app.Off,1),app.VCG(app.On:app.Off,3),-app.VCG(app.On:app.Off,2),app.linetype(app.ltindex)) %Plotting x,z,-y as by convention.
            
            axis(app.threedimUIAxes,app.axistype);
            axis(app.threedimUIAxes,'square');    
            
            plotPrecordialElectrodes(app);
            plotFrontalElectrodes(app);
            hold(app.threedimUIAxes,'off');
            
            app.threedimUIAxes.View = app.view; %Restore view as it was on last dataset.
            
            %app.threedimUIAxes.View; 
        end
        
        %Plots leads on stats tab, and calculates pseudo leads and writes
        %information about biphase to the labels in the tab.
        function plotStatsTab(app)
            %Plot original leads:
            title(app.statsUIAxes1,"III")
            x = linspace(0,length(app.XML.MedianECG.III(app.On:app.Off))/app.fS,length(app.XML.MedianECG.III(app.On:app.Off)));
            plot(app.statsUIAxes1,x,app.XML.MedianECG.III(app.On:app.Off))
            grid(app.statsUIAxes1,"on")
            axis(app.statsUIAxes1,"square")
            
            title(app.statsUIAxes2,"aVF")
            x = linspace(0,length(app.XML.MedianECG.aVF(app.On:app.Off))/app.fS,length(app.XML.MedianECG.aVF(app.On:app.Off)));
            plot(app.statsUIAxes2,x,app.XML.MedianECG.aVF(app.On:app.Off))
            grid(app.statsUIAxes2,"on")
            axis(app.statsUIAxes2,"square")
            
            title(app.statsUIAxes3,"II")
            x = linspace(0,length(app.XML.MedianECG.II(app.On:app.Off))/app.fS,length(app.XML.MedianECG.II(app.On:app.Off)));
            plot(app.statsUIAxes3,x,app.XML.MedianECG.II(app.On:app.Off))
            grid(app.statsUIAxes3,"on")
            axis(app.statsUIAxes3,"square")
            
            title(app.statsUIAxes4,"aVR")
            x = linspace(0,length(app.XML.MedianECG.aVR(app.On:app.Off))/app.fS,length(app.XML.MedianECG.aVR(app.On:app.Off)));
            plot(app.statsUIAxes4,x,app.XML.MedianECG.aVR(app.On:app.Off))
            grid(app.statsUIAxes4,"on")
            axis(app.statsUIAxes4,"square")
            
            title(app.statsUIAxes5,"aVL")
            x = linspace(0,length(app.XML.MedianECG.aVL(app.On:app.Off))/app.fS,length(app.XML.MedianECG.aVL(app.On:app.Off)));
            plot(app.statsUIAxes5,x,app.XML.MedianECG.aVL(app.On:app.Off))
            grid(app.statsUIAxes5,"on")
            axis(app.statsUIAxes5,"square")
            
            title(app.statsUIAxes6,"V1")
            x = linspace(0,length(app.XML.MedianECG.V1(app.On:app.Off))/app.fS,length(app.XML.MedianECG.V1(app.On:app.Off)));
            plot(app.statsUIAxes6,x,app.XML.MedianECG.V1(app.On:app.Off))
            grid(app.statsUIAxes6,"on")
            axis(app.statsUIAxes6,"square")
            
            %Find biphasic/partial IAB
            [p_iab, biphasic_p_wave, sum_p_loop, sum_p_inv_loop] = detectionFile(app.XML.MedianECG.ECG12Leads,app.P_Onset,app.P_Offset); %Function for regression and biphasic check
            
            %Check box for pIAB
            if(p_iab)
                app.statsCheckBoxPartialIAB.Value = 1;
            end
            
            %Type biphasic leads
            if(~isempty(find(biphasic_p_wave,1))) %Finds nonzero entries in array
                app.statsCheckBoxBiphasic.Value = 1;
                biphasicLeads = (find(biphasic_p_wave));
                
                app.countOriginalLabel.Text = int2str(length(biphasicLeads)); %Write the count of biphasic leads
                
                for i=1:length(biphasicLeads)
                    switch biphasicLeads(i)
                        case 1
                            app.leadsLabel.Text = app.leadsLabel.Text + " I"
                        case 2
                            app.leadsLabel.Text = app.leadsLabel.Text + " II"
                        case 3
                            app.leadsLabel.Text = app.leadsLabel.Text + " III"
                        case 4
                            app.leadsLabel.Text = app.leadsLabel.Text + " aVR"
                        case 5
                            app.leadsLabel.Text = app.leadsLabel.Text + " aVL"
                        case 6
                            app.leadsLabel.Text = app.leadsLabel.Text + " aVF"
                        case 7
                            app.leadsLabel.Text = app.leadsLabel.Text + " V1"
                        case 8
                            app.leadsLabel.Text = app.leadsLabel.Text + " V2"
                        case 9
                            app.leadsLabel.Text = app.leadsLabel.Text + " V3"
                        case 10
                            app.leadsLabel.Text = app.leadsLabel.Text + " V4"
                        case 11
                            app.leadsLabel.Text = app.leadsLabel.Text + " V5"
                         case 12
                            app.leadsLabel.Text = app.leadsLabel.Text + " V6"
                        otherwise
                    end
                end
            end
            
            
            %Plot pseudoleads:
            [app.pseudoLeads, app.pseudoElectrodes, app.numerationOfElectrodes] = PseudoLeadsCalc([app.VCG(app.On:app.Off,1),app.VCG(app.On:app.Off,2),app.VCG(app.On:app.Off,3)]);
            
            
            for i = 1:size(app.pseudoLeads,1)
                if (i==2) %To clear the plot on first plot
                    hold(app.Pseudo_UIAxes,'on');
                end
                x = linspace(0,length(app.pseudoLeads(i,:))/app.fS,length(app.pseudoLeads(i,:)));
                plot(app.Pseudo_UIAxes,x,app.pseudoLeads(i,:))
            end
            axis(app.Pseudo_UIAxes,"square")
            
            legend(app.Pseudo_UIAxes,"Lead " + (app.numerationOfElectrodes)*10);
            hold(app.Pseudo_UIAxes,'off');
            grid(app.Pseudo_UIAxes,'on');
            
            
            %Type in text for pseudoleads, if P-wave view is selected.
            if(app.ViewDropDown.Value == 'P')
                %Find biphasic/partial IAB
                [p_iab_pseudo, biphasic_p_wave_pseudo, sum_p_loop, sum_p_inv_loop] = detectionFile(app.pseudoLeads', 1, size(app.pseudoLeads,2)); %Function for regression and biphasic check
                
                %Check box for pIAB for pseudoleads
                if(p_iab_pseudo)
                    app.statsCheckBoxPartialIAB_2.Value = 1;
                end
                
                %Write text for biphasic leads in pseudoleads
                if(~isempty(find(biphasic_p_wave_pseudo,1)))
                    app.statsCheckBoxBiphasic_2.Value = 1;
                    BiphasicElectrodes = find(biphasic_p_wave_pseudo);
                    biphasicLeads_pseudo = app.numerationOfElectrodes(BiphasicElectrodes)*10;


                    % numerationOfElectrodes bruges i en anden sammenhæng
                    % også, som jeg er sikker på er rigtig, men her skal
                    % der bruges en anden vector til at beskrive
                    % rækkefølgen.
                    app.countPseudoLabel_2.Text = int2str(length(biphasicLeads_pseudo)); %Type the count of biphasic leads
                    
                    for i=1:length(biphasicLeads_pseudo)
                        app.leadsLabel_2.Text = app.leadsLabel_2.Text + " " + biphasicLeads_pseudo(i) + "d ";
                    end


                end
            end
            
        end       
    end
    
    methods (Access = private)
        
        function plotPrecordialElectrodes(app)
        axis_size = app.str + 10;% distance away from origo to plot electrode
        v2 = [0; -axis_size]; %First electrode which is the starting point. %First Coordinate is X and last coordinate is Y
        precordial_angles = [-30 0 30 60 90 120]; %Degrees to rotate
        precordial_elec = zeros(2,length(precordial_angles)); %Vector with the coordinates for each electrode
        for i=1:length(precordial_angles)
            phi = precordial_angles(i).*(pi/180);
            R = [cos(phi) -sin(phi); sin(phi) cos(phi)];
            
            precordial_elec(:,i) = R*v2; %Calculate new rotation.
            scatter3(app.threedimUIAxes, precordial_elec(1,i),precordial_elec(2,i),0,'sr','filled')
            text(app.threedimUIAxes, precordial_elec(1,i)+2,precordial_elec(2,i)+2,0,"V"+i)
        end
            
        end
        
        function plotFrontalElectrodes(app)
        axis_size = app.str + 10;% distance away from origo to plot electrode
        I = [axis_size;0]; %First Coordinate is X and Last coordinate is Z
        frontal_angles = [0 -30 -60 -90 -120 30];
        frontal_elec = zeros(2,length(frontal_angles));
        for i=1:length(frontal_angles)
            phi = frontal_angles(i).*(pi/180);
            R = [cos(phi) -sin(phi); sin(phi) cos(phi)];
            
            frontal_elec(:,i) = R*I;
            scatter3(app.threedimUIAxes, frontal_elec(1,i),0,frontal_elec(2,i),'vk','filled')
            switch i
                case 1
                    text(app.threedimUIAxes,frontal_elec(1,i)+4,0,frontal_elec(2,i)+4,"I")
                case 2
                    text(app.threedimUIAxes, frontal_elec(1,i)+4,0,frontal_elec(2,i)+4,"aVR")
                case 3    
                    text(app.threedimUIAxes,frontal_elec(1,i)+4,0,frontal_elec(2,i)+4,"II")
                case 4
                    text(app.threedimUIAxes,frontal_elec(1,i)+4,0,frontal_elec(2,i)+4,"aVF")
                case 5
                    text(app.threedimUIAxes,frontal_elec(1,i)+4,0,frontal_elec(2,i)+4,"III")
                case 6
                    text(app.threedimUIAxes,frontal_elec(1,i)+4,0,frontal_elec(2,i)+4,"aVL")
            end
        end

        end
        
        function plotPseudoElectrodes(app)
            hold(app.threedimUIAxes,'on');
            for i=1:length(app.numerationOfElectrodes)
                label = app.numerationOfElectrodes(i);%J is now indexes of the electrodes returned from pseudoLeadsCalc function
               scatter3(app.threedimUIAxes, app.pseudoElectrodes(i,1), app.pseudoElectrodes(i,2), app.pseudoElectrodes(i,3),'go','filled') 
               text(app.threedimUIAxes, app.pseudoElectrodes(i,1), app.pseudoElectrodes(i,2), app.pseudoElectrodes(i,3),"P"+label) %plot J minus 1 to get correct degrees
            end
%             
%             for i=29:36
%                scatter3(app.threedimUIAxes, app.pseudoElectrodes(i,1), app.pseudoElectrodes(i,2), app.pseudoElectrodes(i,3),'go','filled') 
%                text(app.threedimUIAxes, app.pseudoElectrodes(i,1), app.pseudoElectrodes(i,2), app.pseudoElectrodes(i,3),"P"+(i-1))
%             end
            hold(app.threedimUIAxes,'off');
        end
        
        function RefreshPlots(app)
            app.ResetText;
            
            %app.calcVCG;
            
            app.plot2d;
            app.plot3d;
            app.plotStatsTab;%Must be run before plotPseudoElectrodes since it calculates new pseudoelectrodes.
            app.plotPseudoElectrodes;
            
        end
        
        function ResetText(app)
            
            %Original lead texts            
            app.statsCheckBoxPartialIAB.Value = 0; %Reset checkbox for pIAB
            
            app.statsCheckBoxBiphasic.Value = 0;%Reset checkbox for biphase
            app.leadsLabel.Text = " ";%Reset checkbox for biphase
            app.countOriginalLabel.Text = "";
            
            %Pseudo lead texts
            app.statsCheckBoxPartialIAB_2.Value = 0; %Reset checkbox for pIAB
            
            app.statsCheckBoxBiphasic_2.Value = 0;%Reset checkbox for biphase
            app.leadsLabel_2.Text = " ";%Reset checkbox for biphase pseudo
            app.countPseudoLabel_2.Text = "";
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %addpath("S:\Matlab XML reader")
            testfiledir='Testdata\';
            app.xmlfiles=dir(fullfile(testfiledir,'*xml'));
            
            app.Lamp.Color=[0.39 0.83 0.07];
            
            app.calcVCG;
            app.RefreshPlots;
        end

        % Value changed function: ViewDropDown
        function ViewDropDownValueChanged(app, event)
            value = app.ViewDropDown.Value;
            
            %app.calcVCG; % To update the data before setting new limits
            
            if value=="All"
                app.On=1;
                app.Off=600;
                app.axistype=app.allaxis;
                app.str = 1100; %Axis size of cross to fit the plot
            end
            
            if value=="QRS"
                app.On=app.XML.TestInfo.QOnset;
                app.Off=app.XML.TestInfo.QOffset;
                app.axistype=app.qrsaxis;
                app.str = 1100; %Axis size of cross to fit the plot
            end
            
            if value=="P"
                app.On=app.XML.TestInfo.POnset;
                app.Off=app.XML.TestInfo.POffset;
                app.axistype=app.paxis;
                app.str = 200; %Axis size of cross to fit the plot
            end
            
            app.view = app.threedimUIAxes.View; %Save current view on 3D axis
            app.RefreshPlots;
        end

        % Value changed function: CurrentplotSpinner
        function CurrentplotSpinnerValueChanged(app, event)
            value = app.ViewDropDown.Value;
            app.calcVCG; % To update the data before setting new limits
             
            if value=="All"
                app.On=1;
                app.Off=600;
                app.axistype=app.allaxis;
                app.str = 1100; %Axis size of cross to fit the plot
            end
            
            if value=="QRS"
                app.On=app.XML.TestInfo.QOnset;
                app.Off=app.XML.TestInfo.QOffset;
                app.axistype=app.qrsaxis;
                app.str = 1100; %Axis size of cross to fit the plot
            end
            
            if value=="P"
                app.On=app.XML.TestInfo.POnset;
                app.Off=app.XML.TestInfo.POffset;
                app.axistype=app.paxis;
                app.str = 200; %Axis size of cross to fit the plot
            end
            
            app.view = app.threedimUIAxes.View; %Save current view on 3D axis
            
            app.RefreshPlots;
        end

        % Value changed function: LinetypeDropDown
        function LinetypeDropDownValueChanged(app, event)
            value = app.LinetypeDropDown.Value;
                       
            if value=="o"
                app.ltindex=1;
            end
            
            if value=="."
                app.ltindex=2;
            end
            
            if value=="-"
                app.ltindex=3;
            end
            
            app.view = app.threedimUIAxes.View; %Save current view on 3D axis
            app.RefreshPlots;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DTab_2 = uitab(app.TabGroup);
            app.DTab_2.Title = '3D';
            app.DplaneLabel = uilabel(app.DTab_2);
            app.DplaneLabel.Text = '3D plane';
            app.threedimUIAxes = uiaxes(app.DTab_2);
            title(app.threedimUIAxes, '3D')
            xlabel(app.threedimUIAxes, 'X')
            ylabel(app.threedimUIAxes, 'Y')
            app.DTab = uitab(app.TabGroup);
            app.DTab.Title = '2D';
            app.DplanesLabel = uilabel(app.DTab);
            app.DplanesLabel.Text = '2D planes';
            app.frontal = uiaxes(app.DTab);
            title(app.frontal, 'Frontal')
            xlabel(app.frontal, 'X')
            ylabel(app.frontal, 'Y')
            app.sagital = uiaxes(app.DTab);
            title(app.sagital, 'Sagital')
            xlabel(app.sagital, 'Y')
            ylabel(app.sagital, 'Z')
            app.transversal = uiaxes(app.DTab);
            title(app.transversal, 'Transversal')
            xlabel(app.transversal, 'X')
            ylabel(app.transversal, 'Z')
            app.Lamp = uilamp(app.DTab);
            app.DataloadedLabel = uilabel(app.DTab);
            app.DataloadedLabel.Text = 'Data loaded';
            app.StatsTab = uitab(app.TabGroup);
            app.StatsTab.Title = 'Stats';
            app.GridLayout2 = uigridlayout(app.StatsTab);
            app.ECGleadsPanel = uipanel(app.GridLayout2);
            app.ECGleadsPanel.Title = 'ECG leads';
            app.GridLayout = uigridlayout(app.ECGleadsPanel);
            app.statsUIAxes1 = uiaxes(app.GridLayout);
            title(app.statsUIAxes1, 'Title')
            xlabel(app.statsUIAxes1, 'X(s)')
            ylabel(app.statsUIAxes1, 'Y(µV)')
            app.statsUIAxes2 = uiaxes(app.GridLayout);
            title(app.statsUIAxes2, 'Title')
            xlabel(app.statsUIAxes2, 'X(s)')
            ylabel(app.statsUIAxes2, 'Y(µV)')
            app.statsUIAxes3 = uiaxes(app.GridLayout);
            title(app.statsUIAxes3, 'Title')
            xlabel(app.statsUIAxes3, 'X(s)')
            ylabel(app.statsUIAxes3, 'Y(µV)')
            app.statsUIAxes4 = uiaxes(app.GridLayout);
            title(app.statsUIAxes4, 'Title')
            xlabel(app.statsUIAxes4, 'X(s)')
            ylabel(app.statsUIAxes4, 'Y(µV)')
            app.statsUIAxes5 = uiaxes(app.GridLayout);
            title(app.statsUIAxes5, 'Title')
            xlabel(app.statsUIAxes5, 'X(s)')
            ylabel(app.statsUIAxes5, 'Y(µV)')
            app.statsUIAxes6 = uiaxes(app.GridLayout);
            title(app.statsUIAxes6, 'Title')
            xlabel(app.statsUIAxes6, 'X(s)')
            ylabel(app.statsUIAxes6, 'Y(µV)')
            app.statsCheckBoxBiphasic = uicheckbox(app.GridLayout2);
            app.statsCheckBoxBiphasic.Enable = 'off';
            app.statsCheckBoxBiphasic.Text = {''; ''};
            app.BiphasicPwaveLabel = uilabel(app.GridLayout2);
            app.BiphasicPwaveLabel.Text = 'Biphasic P-wave';
            app.InleadsLabel = uilabel(app.GridLayout2);
            app.InleadsLabel.Text = 'In leads:';
            app.leadsLabel = uilabel(app.GridLayout2);
            app.statsCheckBoxPartialIAB = uicheckbox(app.GridLayout2);
            app.statsCheckBoxPartialIAB.Enable = 'off';
            app.statsCheckBoxPartialIAB.Text = {''; ''};
            app.PartialIABLabel = uilabel(app.GridLayout2);
            app.PartialIABLabel.Text = 'Partial IAB';
            app.Pseudo_UIAxes = uiaxes(app.GridLayout2);
            title(app.Pseudo_UIAxes, 'Pseudo-electrode plots')
            xlabel(app.Pseudo_UIAxes, 'X(s)')
            ylabel(app.Pseudo_UIAxes, 'Y(µV)')
            app.OriginalsLabel = uilabel(app.GridLayout2);
            app.OriginalsLabel.Text = 'Originals';
            app.statsCheckBoxBiphasic_2 = uicheckbox(app.GridLayout2);
            app.statsCheckBoxBiphasic_2.Enable = 'off';
            app.statsCheckBoxBiphasic_2.Text = {''; ''};
            app.BiphasicPwaveLabel_2 = uilabel(app.GridLayout2);
            app.BiphasicPwaveLabel_2.Text = 'Biphasic P-wave';
            app.InleadsLabel_2 = uilabel(app.GridLayout2);
            app.InleadsLabel_2.Text = 'In leads:';
            app.leadsLabel_2 = uilabel(app.GridLayout2);
            app.statsCheckBoxPartialIAB_2 = uicheckbox(app.GridLayout2);
            app.statsCheckBoxPartialIAB_2.Enable = 'off';
            app.statsCheckBoxPartialIAB_2.Text = {''; ''};
            app.PartialIABLabel_2 = uilabel(app.GridLayout2);
            app.PartialIABLabel_2.Text = 'Partial IAB';
            app.PseudoLabel = uilabel(app.GridLayout2);
            app.PseudoLabel.Text = 'Pseudo';
            app.TotalcountLabel = uilabel(app.GridLayout2);
            app.TotalcountLabel.Text = 'Total count';
            app.countOriginalLabel = uilabel(app.GridLayout2);
            app.countOriginalLabel.Text = 'Label2';
            app.countPseudoLabel_2 = uilabel(app.GridLayout2);
            app.countPseudoLabel_2.Text = 'Label2';
            app.TotalcountLabel_2 = uilabel(app.GridLayout2);
            app.TotalcountLabel_2.Text = 'Total count';
            app.CurrentplotSpinnerLabel = uilabel(app.UIFigure);
            app.CurrentplotSpinnerLabel.Text = 'Current plot';
            app.CurrentplotSpinner = uispinner(app.UIFigure);
            app.CurrentplotSpinner.ValueChangedFcn = createCallbackFcn(app, @CurrentplotSpinnerValueChanged, true);
            app.CurrentplotSpinner.Value = 1;
            app.ViewDropDownLabel = uilabel(app.UIFigure);
            app.ViewDropDownLabel.Text = 'View';
            app.ViewDropDown = uidropdown(app.UIFigure);
            app.ViewDropDown.Items = {'All', 'QRS', 'P'};
            app.ViewDropDown.ValueChangedFcn = createCallbackFcn(app, @ViewDropDownValueChanged, true);
            app.ViewDropDown.Value = 'All';
            app.LinetypeDropDownLabel = uilabel(app.UIFigure);
            app.LinetypeDropDownLabel.Text = 'Linetype';
            app.LinetypeDropDown = uidropdown(app.UIFigure);
            app.LinetypeDropDown.Items = {'o', '.', '-'};
            app.LinetypeDropDown.ValueChangedFcn = createCallbackFcn(app, @LinetypeDropDownValueChanged, true);
            app.LinetypeDropDown.Value = 'o';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VCGapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end